classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
    end

    
    properties (Access = public)
        pos % Description
    end
    
    methods (Access = private)
        
        function mouseDownCallback(app,src,event)
            disp('Have a nice day!');
            while (1)
                pause(0.5);
                if ismousedpressed==0
                    break;
                end
                disp('mouse still pressed');
                disp(get(app.UIFigure,'CurrentPoint'));
                app.pos=get(app.UIFigure,'CurrentPoint');
            end
        end

        function mouseUpCallback(app,src,event)
            disp("Have a good night!");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.WindowButtonDownFcn=@app.mouseDownCallback;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end