classdef my_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        timesTextArea       matlab.ui.control.TextArea
        timesTextAreaLabel  matlab.ui.control.Label
        Button_2            matlab.ui.control.Button
        Button              matlab.ui.control.Button
        TextArea            matlab.ui.control.TextArea
        sliceSlider         matlab.ui.control.Slider
        sliceSliderLabel    matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        ImageData; % the data to be displayed. This data is changable
        im; % the axes object.
        mouseflag;
        test;
        left_click_range;
        right_click_range;
    end
    
    
    methods (Access = public)
        
        function mouseDownCallback(app,src,event)
            pos=get(app.UIFigure,'CurrentPoint');
            if (pos(1)>=app.left_click_range(1,1) && pos(1)<=app.left_click_range(1,2) && ... 
                pos(2)>=app.left_click_range(2,1) && pos(2)<=app.left_click_range(2,2)) 
                    app.sliceSlider.Value=max([app.sliceSlider.Value-1,1]);
                        changingValue = floor(app.sliceSlider.Value);
                        app.im.CData=app.ImageData.data(:,:,:,max([1,changingValue]));
                        app.TextArea.Value=[num2str(changingValue),'/',num2str(app.ImageData.num_frame)];
                        app.timesTextArea.Value=[num2str(round(changingValue*app.ImageData.frame_period,2)),'/',num2str(app.ImageData.total_time)];
                    pause(0.3)
                    while (1)
                        disp(num2str(ismousedpressed));
                        if ismousedpressed==0
                            break;
                        end
                        app.sliceSlider.Value=max([app.sliceSlider.Value-1,1]);
                        changingValue = floor(app.sliceSlider.Value);
                        app.im.CData=app.ImageData.data(:,:,:,max([1,changingValue]));
                        app.TextArea.Value=[num2str(changingValue),'/',num2str(app.ImageData.num_frame)];
                        app.timesTextArea.Value=[num2str(round(changingValue*app.ImageData.frame_period,2)),'/',num2str(app.ImageData.total_time)];
                        pause(0.01); 
                        
                    end
            end

            if (pos(1)>=app.right_click_range(1,1) && pos(1)<=app.right_click_range(1,2) && ... 
                pos(2)>=app.right_click_range(2,1) && pos(2)<=app.right_click_range(2,2))
                     app.sliceSlider.Value=min([app.ImageData.num_frame,app.sliceSlider.Value+1]);
                        changingValue = floor(app.sliceSlider.Value);
                        app.im.CData=app.ImageData.data(:,:,:,max([1,changingValue]));
                        app.TextArea.Value=[num2str(changingValue),'/',num2str(app.ImageData.num_frame)];
                        app.timesTextArea.Value=[num2str(round(changingValue*app.ImageData.frame_period,2)),'/',num2str(app.ImageData.total_time)];
                    pause(0.3)
                    while (1)   
                        disp(num2str(ismousedpressed));
                        if ismousedpressed==0
                            break;
                        end
                        app.sliceSlider.Value=min([app.ImageData.num_frame,app.sliceSlider.Value+1]);
                        changingValue = floor(app.sliceSlider.Value);
                        app.im.CData=app.ImageData.data(:,:,:,max([1,changingValue]));
                        app.TextArea.Value=[num2str(changingValue),'/',num2str(app.ImageData.num_frame)];
                        app.timesTextArea.Value=[num2str(round(changingValue*app.ImageData.frame_period,2)),'/',num2str(app.ImageData.total_time)];
                        pause(0.01);

                    end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data)
            if ~isa(data,'ImagingData')
                error('Incorrect type of data transferred.');
            end
            % Preparing for mouse pressing behavior
            if ~libisloaded('user32')
                try
                loadlibrary('C:\WINDOWS\system32\user32.dll', 'user32.h');
                catch
            % user.h is removed on certain recent version of Windows
            % We replace with a simple customized header file 
                here = fileparts(mfilename('fullpath'));
                loadlibrary('C:\WINDOWS\system32\user32.dll', [here '\WinMouse.h']);
                end
            end
            % initializing the window
            app.ImageData=data; % my self-defined class ImagingData
            app.ImageData.data(:,:,3,:)=zeros(app.ImageData.y_size,app.ImageData.x_size,app.ImageData.num_frame);
            app.ImageData.data=uint8(app.ImageData.data/16); % converting from 12-depth into 8-depth           
            ratio=[app.ImageData.x_size,app.ImageData.y_size]/max([app.ImageData.x_size,app.ImageData.y_size]);
            app.UIAxes.Position=[200,app.sliceSlider.Position(2)+15-(256-256*app.ImageData.y_size/app.ImageData.x_size)/2,256,256]; % the window is 256 pixels in size
            app.UIAxes.PlotBoxAspectRatio=[ratio,1];
            app.im=imagesc(app.UIAxes,app.ImageData.data(:,:,:,1));
            app.UIFigure.Name=app.ImageData.window_name;
            if app.ImageData.num_frame==1 % if only one frame, then no interactivity.
               app.sliceSlider.delete();
               app.Button.delete();
               app.Button_2.delete();
               app.TextArea.delete();
               app.timesTextArea.delete();
               app.timesTextAreaLabel.delete();
               app.sliceSliderLabel.delete();
            else
                app.sliceSlider.Limits=[1,app.ImageData.num_frame];            
                app.TextArea.Value=['1/',num2str(app.ImageData.num_frame)];
                app.timesTextArea.Value=['0/',num2str(app.ImageData.total_time)];
    
                app.UIFigure.WindowButtonDownFcn=@app.mouseDownCallback; % setting callback instead of stupid button pressing
                app.left_click_range=zeros(2,2); 
                app.left_click_range(1,1)=app.Button.Position(1);app.left_click_range(1,2)=app.Button.Position(1)+app.Button.Position(3);
                app.left_click_range(2,1)=app.Button.Position(2);app.left_click_range(2,2)=app.Button.Position(2)+app.Button.Position(4);
                app.right_click_range=zeros(2,2); 
                app.right_click_range(1,1)=app.Button_2.Position(1);app.right_click_range(1,2)=app.Button_2.Position(1)+app.Button_2.Position(3);
                app.right_click_range(2,1)=app.Button_2.Position(2);app.right_click_range(2,2)=app.Button_2.Position(2)+app.Button_2.Position(4);
            end
        end

        % Callback function
        function OpenMenuSelected(app, event)
            disp('ok');
        end

        % Value changing function: sliceSlider
        function sliceSliderValueChanging(app, event)
            changingValue = floor(event.Value);
            app.im.CData=app.ImageData.data(:,:,:,max([1,changingValue]));
            app.TextArea.Value=[num2str(changingValue),'/',num2str(app.ImageData.num_frame)];
            app.timesTextArea.Value=[num2str((changingValue-1)*app.ImageData.frame_period),'/',num2str(app.ImageData.total_time)];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.sliceSliderLabel = uilabel(app.UIFigure);
            app.sliceSliderLabel.Text = 'slice #';
            app.sliceSlider = uislider(app.UIFigure);
            app.sliceSlider.MajorTicks = [];
            app.sliceSlider.MajorTickLabels = {};
            app.sliceSlider.ValueChangingFcn = createCallbackFcn(app, @sliceSliderValueChanging, true);
            app.sliceSlider.MinorTicks = [];
            app.sliceSlider.Value = 1;
            app.TextArea = uitextarea(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Text = '<';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Text = '>';
            app.timesTextAreaLabel = uilabel(app.UIFigure);
            app.timesTextAreaLabel.Text = 'time (s)';
            app.timesTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = my_test(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end